classdef ParameterSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ResultsTable                matlab.ui.control.Table
        CancelButton                matlab.ui.control.Button
        ApplyButton                 matlab.ui.control.Button
        StatusLabel                 matlab.ui.control.Label
        RankByDropDown              matlab.ui.control.DropDown
        RankByDropDownLabel         matlab.ui.control.Label
        RunButton                   matlab.ui.control.Button
        MaxCombosEditField          matlab.ui.control.NumericEditField
        MaxCombosEditFieldLabel     matlab.ui.control.Label
        ComboCountLabel             matlab.ui.control.Label
        SweepTable                  matlab.ui.control.Table
        BuildRowsButton             matlab.ui.control.Button
        SkeletonMethodListBox       matlab.ui.control.ListBox
        SkeletonMethodListBoxLabel  matlab.ui.control.Label
        EnhanceMethodListBox        matlab.ui.control.ListBox
        EnhanceMethodListBoxLabel   matlab.ui.control.Label
    end

    
    properties (Access = public)
        OutputParams = [] % Description
    end

    properties (Access = private)
        Spec                        % what to sweep: see funcSweepSpec in AnalyzER_app
        Rows                        % sweep table (parameterSelectorStepRows)
        RowMethods = cell(0, 3)     % candidate methods per step at the last Build
        Combos                      % combinations of the last run
        Results                     % funcSweepRun table of the last run
    end

    methods (Access = private)
        function refreshMethodList(app)
            [items, sel] = parameterSelectorMethodItems(app.Spec, app.EnhanceMethodListBox.Value);
            app.SkeletonMethodListBox.Items = items;
            app.SkeletonMethodListBox.Value = sel;
        end

        function refreshSweepTable(app)
            T = parameterSelectorTableData(app.Rows);
            app.SweepTable.Data = T;
            app.SweepTable.ColumnName = T.Properties.VariableNames;
            app.SweepTable.ColumnEditable = [false false false false true true true true];
        end

        function updateComboCount(app)
            app.ComboCountLabel.FontColor = [0 0 0];
            app.RunButton.Enable = 'off';
            if isempty(app.Rows)
                app.ComboCountLabel.Text = 'Combinations: 0';
                return
            end
            try
                combos = parameterSelectorExpandGrid(app.Rows, app.RowMethods);
            catch ME
                app.ComboCountLabel.Text = ['Combinations: ' ME.message];
                app.ComboCountLabel.FontColor = [0.8 0 0];
                return
            end
            n = numel(combos);
            if isempty(combos(1).set), n = 0; end
            app.ComboCountLabel.Text = sprintf('Combinations: %d', n);
            if n > app.MaxCombosEditField.Value
                app.ComboCountLabel.FontColor = [0.8 0 0];
            elseif n > 0
                app.RunButton.Enable = 'on';
            end
        end

        function refreshResultsTable(app)
            if isempty(app.Results)
                app.ResultsTable.Data = [];
                app.ResultsTable.ColumnName = {};
                return
            end
            T = parameterSelectorResultsTable(app.Results, app.RankByDropDown.Value);
            app.ResultsTable.Data = T;
            app.ResultsTable.ColumnName = T.Properties.VariableNames;
        end

        function stop = reportProgress(app, d, k, n) %#ok<INUSD>
            d.Value = k / n;
            d.Message = sprintf('Combination %d of %d', k, n);
            stop = d.CancelRequested;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, spec)
            % spec: .title .steps .meta .metrics .runFn (funcSweepSpec in
            % AnalyzER_app). Put this app's own src/ folder on the path.
            appRoot = fileparts(which('ParameterSelector'));
            if ~isempty(appRoot) && exist(fullfile(appRoot, 'src'), 'dir')
                addpath(fullfile(appRoot, 'src'));
            end
            app.Spec = spec;
            app.OutputParams = [];
            app.UIFigure.Name = spec.title;

            app.EnhanceMethodListBoxLabel.Text = 'Steps to tune';
            app.EnhanceMethodListBox.Items = {spec.steps.label};
            app.EnhanceMethodListBox.ItemsData = 1:numel(spec.steps);
            app.EnhanceMethodListBox.Value = numel(spec.steps);
            app.EnhanceMethodListBox.ValueChangedFcn = @(~, ~) app.refreshMethodList();
            app.SkeletonMethodListBoxLabel.Text = 'Candidate methods';
            app.refreshMethodList();

            app.RankByDropDown.Items = spec.metrics;
            app.RankByDropDown.Value = spec.metrics{1};
            app.MaxCombosEditField.Value = 200;
            colNames = {'Step', 'Method', 'Parameter', 'Current', 'Tune', 'Min', 'Inc', 'Max'};
            app.SweepTable.ColumnName = colNames;
            app.SweepTable.Data = cell2table(cell(0, 8), 'VariableNames', colNames);
            app.ResultsTable.ColumnName = {};
            app.ComboCountLabel.Text = 'Combinations: 0';
            app.RunButton.Enable = 'off';
            app.StatusLabel.Text = '';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Do NOT delete(app) here -- the caller still needs to read
            % OutputParams after uiwait(app.UIFigure) returns. Just hide and
            % resume; the caller deletes the app once it has read OutputParams
            % (mirrors FungalNetworkAnalysis.m's confirm-then-apply pattern rather
            % than the legacy tool's silent overwrite-on-OK).
            app.OutputParams = [];
            uiresume(app.UIFigure);
        end

        % Button pushed function: BuildRowsButton
        function BuildRowsButtonPushed(app, event)
            [app.Rows, app.RowMethods] = parameterSelectorSelectionRows(app.Spec, ...
                app.EnhanceMethodListBox.Value, app.SkeletonMethodListBox.Value);
            app.refreshSweepTable();
            app.updateComboCount();
            app.StatusLabel.Text = '';  
        end

        % Cell edit callback: SweepTable
        function SweepTableCellEdit(app, event)
            fieldNames = {'step', 'method', 'parameter', 'current', 'tune', 'min', 'inc', 'max'};
            r = event.Indices(1);
            c = event.Indices(2);
            app.Rows(r).(fieldNames{c}) = event.NewData;
            app.updateComboCount();
        end

        % Value changed function: MaxCombosEditField
        function MaxCombosEditFieldValueChanged(app, event)
            app.updateComboCount();
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            try
                combos = parameterSelectorExpandGrid(app.Rows, app.RowMethods);
            catch ME
                uialert(app.UIFigure, ME.message, 'Invalid sweep range');
                return
            end
            if numel(combos) > app.MaxCombosEditField.Value
                uialert(app.UIFigure, sprintf( ...
                    '%d combinations exceeds the limit of %d. Narrow the sweep or raise the limit.', ...
                    numel(combos), app.MaxCombosEditField.Value), 'Too many combinations');
                return
            end
            app.Combos = combos;

            d = uiprogressdlg(app.UIFigure, 'Title', 'Running sweep', ...
                'Message', 'Starting...', 'Cancelable', 'on');
            cleanupObj = onCleanup(@() close(d)); %#ok<NASGU>
            try
                results = app.Spec.runFn(combos, @(k, n) app.reportProgress(d, k, n));
            catch ME
                uialert(app.UIFigure, ME.message, 'Sweep failed');
                return
            end
            app.Results = results;
            app.refreshResultsTable();
            app.StatusLabel.Text = sprintf('Sweep complete: %d of %d combinations run.', height(results), numel(combos));
        end

        % Value changed function: RankByDropDown
        function RankByDropDownValueChanged(app, event)
            app.refreshResultsTable();
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            sel = app.ResultsTable.Selection;
            if isempty(sel)
                uialert(app.UIFigure, 'Select a row in the results table first.', 'No selection');
                return
            end
            comboIdx = app.ResultsTable.Data.combo(sel(1));
            app.OutputParams = struct('set', {app.Combos(comboIdx).set});
            app.UIFigure.Visible = 'off';
            uiresume(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.OutputParams = [];
            app.UIFigure.Visible = 'off';
            uiresume(app.UIFigure); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.EnhanceMethodListBoxLabel = uilabel(app.UIFigure);
            app.EnhanceMethodListBoxLabel.Text = 'Enhance candidate methods';
            app.EnhanceMethodListBox = uilistbox(app.UIFigure);
            app.EnhanceMethodListBox.Multiselect = 'on';
            app.EnhanceMethodListBox.Value = {'Item 1'};
            app.SkeletonMethodListBoxLabel = uilabel(app.UIFigure);
            app.SkeletonMethodListBoxLabel.Text = 'Skeleton candidate methods';
            app.SkeletonMethodListBox = uilistbox(app.UIFigure);
            app.SkeletonMethodListBox.Multiselect = 'on';
            app.SkeletonMethodListBox.Value = {'Item 1'};
            app.BuildRowsButton = uibutton(app.UIFigure, 'push');
            app.BuildRowsButton.ButtonPushedFcn = createCallbackFcn(app, @BuildRowsButtonPushed, true);
            app.BuildRowsButton.Text = 'Build sweep table';
            app.SweepTable = uitable(app.UIFigure);
            app.SweepTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SweepTable.RowName = {};
            app.SweepTable.CellEditCallback = createCallbackFcn(app, @SweepTableCellEdit, true);
            app.ComboCountLabel = uilabel(app.UIFigure);
            app.ComboCountLabel.Text = 'Combinations: 0';
            app.MaxCombosEditFieldLabel = uilabel(app.UIFigure);
            app.MaxCombosEditFieldLabel.Text = 'Max combos';
            app.MaxCombosEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxCombosEditField.Limits = [1 Inf];
            app.MaxCombosEditField.ValueDisplayFormat = '%.0f';
            app.MaxCombosEditField.ValueChangedFcn = createCallbackFcn(app, @MaxCombosEditFieldValueChanged, true);
            app.MaxCombosEditField.Value = 200;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run Sweep';
            app.RankByDropDownLabel = uilabel(app.UIFigure);
            app.RankByDropDownLabel.Text = 'Rank by:';
            app.RankByDropDown = uidropdown(app.UIFigure);
            app.RankByDropDown.ValueChangedFcn = createCallbackFcn(app, @RankByDropDownValueChanged, true);
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply selected';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ResultsTable = uitable(app.UIFigure);
            app.ResultsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ResultsTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParameterSelector(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end